classdef Cogeneration_Cycle_App_Formatted < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        reset_graph_button             matlab.ui.control.Button
        reset_button                   matlab.ui.control.Button
        Disclaimer                     matlab.ui.control.Label
        mdot_slider                    matlab.ui.control.Slider
        p1_slider                      matlab.ui.control.Slider
        t16_slider                     matlab.ui.control.Slider
        p13_slider                     matlab.ui.control.Slider
        p8_slider                      matlab.ui.control.Slider
        t7_slider                      matlab.ui.control.Slider
        p5_slider                      matlab.ui.control.Slider
        t4_slider                      matlab.ui.control.Slider
        p2_slider                      matlab.ui.control.Slider
        t1_slider                      matlab.ui.control.Slider
        eta_cogeneration               matlab.ui.control.NumericEditField
        CyclePowerLabel_4              matlab.ui.control.Label
        eta_thermal                    matlab.ui.control.NumericEditField
        CyclePowerLabel_3              matlab.ui.control.Label
        eta_electrical                 matlab.ui.control.NumericEditField
        CyclePowerLabel_2              matlab.ui.control.Label
        W_cyc_P                        matlab.ui.control.NumericEditField
        CyclePowerLabel                matlab.ui.control.Label
        finish_lamp                    matlab.ui.control.Lamp
        FinishLabel                    matlab.ui.control.Label
        calc_button                    matlab.ui.control.Button
        mdot                           matlab.ui.control.NumericEditField
        MassFlowRatekgsEditFieldLabel  matlab.ui.control.Label
        t16_disp                       matlab.ui.control.NumericEditField
        TemperatureT16CLabel           matlab.ui.control.Label
        p13_disp                       matlab.ui.control.NumericEditField
        Pressurep13MPaLabel            matlab.ui.control.Label
        p8_disp                        matlab.ui.control.NumericEditField
        Pressurep8barLabel             matlab.ui.control.Label
        t7_disp                        matlab.ui.control.NumericEditField
        TemperatureT7CEditFieldLabel   matlab.ui.control.Label
        p5_disp                        matlab.ui.control.NumericEditField
        Pressurep5barLabel             matlab.ui.control.Label
        t4_disp                        matlab.ui.control.NumericEditField
        TemperatureT4CEditFieldLabel   matlab.ui.control.Label
        p2_disp                        matlab.ui.control.NumericEditField
        Pressurep2barLabel             matlab.ui.control.Label
        p1_disp                        matlab.ui.control.NumericEditField
        Pressurep1barLabel             matlab.ui.control.Label
        t1_disp                        matlab.ui.control.NumericEditField
        TemperatureT1CEditFieldLabel   matlab.ui.control.Label
        Ts_graph                       matlab.ui.control.UIAxes
    end


    methods (Access = private)


        function [P,h,s,T] = calculations(app)
            %%%%%%%%%%%%%%%%%%%%%%%
            m_dot = app.mdot.Value;
            P = [zeros(1,20)];
            h = [zeros(1,20)];
            s = [zeros(1,20)];
            T = [zeros(1,20)];
            app.finish_lamp.Color = [1.00,1.00,1.00];
            %%%%%%%%%%%%%%%%%%%%%%%
            T(1) = app.t1_disp.Value;
            P(1) = app.p1_disp.Value;
            P(2) = app.p2_disp.Value;
            T(4) = app.t4_disp.Value;
            P(5) = app.p5_disp.Value;
            T(7) = app.t7_disp.Value;
            P(8) = app.p8_disp.Value;
            P(13) = app.p13_disp.Value;
            T(16) = app.t16_disp.Value;
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            s(1) = XSteam('s_pT',P(1),T(1));
            h(1) = XSteam('h_pT',P(1),T(1));  %%%%State 1

            s(2) = s(1);
            T(2) = XSteam('T_ps',P(2),s(2));
            h(2) = XSteam('h_ps',P(2),s(2)); %%%%State 2

            s(3) = s(1);
            %s3_i = s(3);
            h(3) = s_h_V(app,"s_h",s(3));
            P(3) = XSteam('p_hs',h(3),s(3));
            T(3) = XSteam('T_ps',P(3),s(3)); %%%%State 3

            P(4) = P(3);
            h(4) = XSteam('h_pT',P(4),T(4));
            s(4) = XSteam('s_pT',P(4),T(4)); %%%%State 4

            s(5) = s(4);
            h(5) = XSteam('h_ps',P(5),s(5));
            T(5) = XSteam('T_ps',P(5),s(5)); %%%%State 5

            s(6) = s(5);
            h(6) = s_h_V(app,"s_h",s(6));
            P(6) = XSteam('p_hs',h(6),s(6));
            T(6) = XSteam('T_hs',h(6),s(6)); %%%%State 6

            P(7) = P(6);
            h(7) = XSteam('h_pT',P(7),T(7));
            s(7) = XSteam('s_pT',P(7),T(7)); %%%%State 7

            s(8) = s(7);
            T(8) = XSteam('T_ps',P(8),s(8));
            h(8) = XSteam('h_ps',P(8),s(8)); %%%%State 8

            s(9) = s(8);
            h(9) = s_h_V(app,"s_h",s(9));
            P(9) = XSteam('p_hs', h(9),s(9));
            T(9) = XSteam('T_hs',h(9),s(9)); %%%%State 9

            P(10) = P(9);
            h(10) = XSteam('hL_p',P(10));
            s(10) = XSteam('sL_p',P(10)); 
            T(10) = XSteam('T_ph',P(10),h(10)); %%%%State 10

            s(11) = s(10);
            P(11) = P(8);
            h(11) = XSteam('h_ps',P(11),s(11));
            T(11) = XSteam('T_ph',P(11),h(11)); %%%%State 11

            P(12) = P(8);
            h(12) = XSteam('hL_p',P(12));
            s(12) = XSteam('sL_p',P(12));
            T(12) = XSteam('T_ph',P(12),h(12)); %%%%State 12

            s(13) = s(12);
            h(13) = XSteam('h_ps',P(13),s(13));
            T(13) = XSteam('T_ps',P(13),s(13)); %%%%State 13

            P(14) = P(13);
            h(14) = XSteam('hL_p',P(14));
            s(14) = XSteam('sL_p',P(14));
            T(14) = XSteam('T_ph',P(14),h(14)); %%%%State 14

            s(15) = s(14);
            P(15) = P(1);
            h(15) = XSteam('h_ps',P(15),s(15));
            T(15) = XSteam('T_ps',P(15),s(15)); %%%%State 15

            P(16) = P(1);
            h(16) = XSteam('h_pT',P(16),T(16));
            s(16) = XSteam('s_pT',P(16),T(16)); %%%%State 16

            P(17) = P(2);
            h(17) = XSteam('hL_p',P(17));
            s(17) = XSteam('sL_p',P(17));
            T(17) = XSteam('T_ph',P(17),h(17)); %%%%State 17

            h(18) = h(17);
            P(18) = P(5);
            s(18) = XSteam('s_ph',P(18),h(18));
            T(18) = XSteam('T_ph',P(18),h(18)); %%%%State 18

            P(19) = P(5);
            h(19) = XSteam('hL_p',P(19));
            s(19) = XSteam('sL_p',P(19));
            T(19) = XSteam('T_ph',P(19),h(19)); %%%%State 19

            h(20) = h(19);
            P(20) = P(8);
            s(20) = XSteam('s_ph',P(20),h(20));
            T(20) = XSteam('T_ph',P(20),h(20)); %%%%State 20

            %%%%%%%%%%%%%%%%%%%%%%%%%%%  5.2 EXTRACTION FRACTIONS    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            y_fraction = (h(16)-h(15)) / (h(2)-h(17));
            y_prime_fraction = (h(14)-h(13) +(y_fraction*(-h(18)+h(19)))) / (h(5)-h(19));
            y_double_prime_fraction = -1 * (((-h(11)*(1-y_fraction-y_prime_fraction)) - (h(20)*(y_fraction+y_prime_fraction)) + h(12)) / (h(8)-h(11)));
            %%%%%%%%%%%%%%%%%%%%%%%%%%% 5.3 TURBINE %%%%%%%%%%%%%%%%%%%
            h_hpt = h(1)-h(2) -(y_fraction*(h(2)-h(3)));
            h_ipt = ((1-y_fraction)*(h(4)-h(5))) + ((1-y_fraction-y_prime_fraction)*(h(5)-h(6)));
            h_lpt = ((1-y_prime_fraction)*(h(7)-h(8))) + ((1-y_fraction-y_prime_fraction-y_double_prime_fraction)*(h(8)-h(9)));
            h_turb_total = h_hpt+h_ipt+h_lpt;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.4 PUMP %%%%%%%%%%%%%%%%%%%%%%%%%
            h_p0 = h(10) - h(11);
            h_p1 = h(13) - h(12);
            h_p2 = h(15) - h(14);
            h_p_total = h_p0 + h_p1 + h_p2;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.5 MASS FLOW RATE CYCLE %%%%%%%%%%
            %m_dot = (h_turb_total-h_p_total);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.6 CONDENSER %%%%%%%%%%%%%%%%%%%%
            h_cond = h(9) - h(10);
            Q_out_P = h_cond * m_dot;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.7 TOTAL WORK %%%%%%%%%%%%%%%%
            W_out_P = h_turb_total * m_dot;
            W_in_P = h_p_total * m_dot;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.8 BOILER %%%%%%%%%%%%%%%%%%
            h_boiler = h_p_total + h_turb_total + h_cond;
            Q_in_P = h_boiler * m_dot;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.9 DISTRICT HEAT %%%%%%%%%%%%
            h_dist_in = 1468.3;
            h_dist_out = 1552.4;
            del_h_dist = h_dist_out - h_dist_in;
            m_dot_dist = 15000 / del_h_dist;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.10 WASTE HEAT %%%%%%%%%%%%%%
            Q_waste = (m_dot * h_cond) - (m_dot_dist * del_h_dist);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 5.11 EFFICIENCY %%%%%%%%%%%%%
            eta_electical = (W_out_P - W_in_P) / Q_in_P;
            eta_thermal = Q_out_P / Q_in_P;
            eta_cogeneration = eta_electical + eta_thermal;

            app.W_cyc_P.Value = (W_out_P + W_in_P)/1000;
            app.eta_electrical.Value = eta_electical * 100;
            app.eta_thermal.Value = eta_thermal * 100;
            app.eta_cogeneration.Value = eta_cogeneration * 100;

        end

        function [inter] = s_h_V(app,arg,inp)
            s_t  = [9.1562
                9.0514
                9.0257
                9.0003
                8.9501
                8.9008
                8.8765
                8.8524
                8.8285
                8.8048
                8.7814
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                4.4298];
            h_t = [2501.4
                2508.7
                2510.6
                2512.4
                2516.1
                2519.8
                2521.6
                2523.4
                2525.3
                2527.1
                2528.9
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                2099.3];
            s_t = transpose(s_t);
            h_t = transpose(h_t);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (arg == "s_h")
                inter = interp1(s_t,h_t,inp,'spline');
            elseif (arg == "h_s")
                inter = interp1(h_t,s_t,inp,'spline');
            end
        end


        function [inter] = s_h_L(app,arg,inp)
            s_t = [0
                0.061
                0.0761
                0.0912
                0.1212
                0.151
                0.1658
                0.1806
                0.1953
                0.2099
                0.2245
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                4.4298];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            h_t = [0.01
                16.78
                20.98
                25.2
                33.6
                42.01
                46.2
                50.41
                54.6
                58.8
                62.99
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                2099.3];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            s_t = transpose(s_t);
            h_t = transpose(h_t);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if (arg == 1)
                inter = interp1(s_t,h_t,inp,'spline');
            elseif (arg == 2)
                inter = interp1(h_t,s_t,inp,'spline');
            end
        end


    end

    methods (Access = public)

        function Ts_graph_func(app,P,h,s,T)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            T_lin = linspace(0,450,1000); % range of temperatures
            svap = arrayfun(@(t) XSteam('sV_T',t),T_lin);
            sliq = arrayfun(@(t) XSteam('sL_T',t),T_lin);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            s_2_17 = linspace(s(2),s(17),200);
            T_2_17 = arrayfun(@(t) XSteam('T_ps',P(2),t),s_2_17); %%%% State 2 -> 17

            s_5_19 = linspace(s(5),s(19),200);
            T_5_19 = arrayfun(@(t) XSteam('T_ps',P(5),t),s_5_19); %%%% State 5 -> 19

            s_8_12 = linspace(s(8),s(12),200);
            T_8_12 = arrayfun(@(t) XSteam('T_ps',P(8),t),s_8_12); %%%% State 8 -> 12

            s_15_16_1 = linspace(s(15),s(1),200);
            T_15_16_1 = arrayfun(@(t) XSteam('T_ps',P(1),t),s_15_16_1); %%%% State 15 -> 16 -> 1

            s_9_10 = linspace(s(9),s(10),200);
            T_9_10 = arrayfun(@(t) XSteam('T_ps',P(9),t),s_9_10); %%%% State 9 -> 10

            s_10_11 = [s(10) s(11)];
            T_10_11 = [T(10) T(11)]; %%%% State 10 -> 11

            s_12_13 = [s(12) s(13)];
            T_12_13 = [T(12) T(13)]; %%%% State 12 -> 13

            s_14_15 = [s(14) s(15)];
            T_14_15 = [T(14) T(15)]; %%%% State 14 -> 15

            s_11_12 = linspace(s(11),s(12),200);
            T_11_12 = arrayfun(@(t) XSteam('T_ps',P(11),t),s_11_12); %%%% State 11 -> 12

            s_13_14 = linspace(s(13),s(14),200);
            T_13_14 = arrayfun(@(t) XSteam('T_ps',P(13),t),s_13_14); %%%% State 13 -> 14

            s_17_18 = linspace(s(17),s(18),200);
            T_17_18 = arrayfun(@(t) XSteam('T_hs',h(17),t),s_17_18); %%%% State 17 -> 18

            s_19_20 = linspace(s(19),s(20),200);
            T_19_20 = arrayfun(@(t) XSteam('T_hs',h(19),t),s_19_20); %%%% State 19 -> 20

            s_1_3 = [s(1) s(3)];
            T_1_3 = [T(1) T(3)]; %%%% State 1 -> 3

            s_4_6 = [s(4) s(6)];
            T_4_6 = [T(4) T(6)]; %%%% State 4 -> 6

            s_7_9 = [s(7) s(9)];
            T_7_9 = [T(7) T(9)]; %%%% State 7 -> 9

            s_3_4 = linspace(s(3),s(4),200);
            T_3_4 = arrayfun(@(t) XSteam('T_ps',P(3),t),s_3_4); %%%% State 3 -> 4

            s_6_7 = linspace(s(6),s(7),200);
            T_6_7 = arrayfun(@(t) XSteam('T_ps',P(6),t),s_6_7); %%%% State 6 -> 7


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.Ts_graph.YLim = [0,600];
            app.Ts_graph.XLim = [0,9];

            hold (app.Ts_graph, 'on')
            scatter(app.Ts_graph,s,T)

            plot(app.Ts_graph,svap,T_lin,'k-')
            plot(app.Ts_graph,sliq,T_lin, 'k-')

            plot(app.Ts_graph,s_2_17,T_2_17, 'b')
            plot(app.Ts_graph,s_5_19,T_5_19, 'b')
            plot(app.Ts_graph,s_8_12,T_8_12, 'b')
            plot(app.Ts_graph,s_15_16_1,T_15_16_1,'g')
            plot(app.Ts_graph,s_9_10,T_9_10, 'g')

            plot(app.Ts_graph,s_10_11,T_10_11, 'g')
            plot(app.Ts_graph,s_12_13,T_12_13, 'g')
            plot(app.Ts_graph,s_14_15,T_14_15, 'g')

            plot(app.Ts_graph,s_11_12,T_11_12, 'g')
            plot(app.Ts_graph,s_13_14,T_13_14, 'g')

            plot(app.Ts_graph,s_17_18,T_17_18, 'r')
            plot(app.Ts_graph,s_19_20,T_19_20, 'r')

            plot(app.Ts_graph,s_1_3,T_1_3, 'g')
            plot(app.Ts_graph,s_4_6,T_4_6, 'g')
            plot(app.Ts_graph,s_7_9,T_7_9, 'g')

            plot(app.Ts_graph,s_3_4,T_3_4, 'g')
            plot(app.Ts_graph,s_6_7,T_6_7, 'g')

            hold (app.Ts_graph, 'off')
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.t1_disp.Value = 500;
            app.p1_disp.Value = 200;
            app.p2_disp.Value = 100;
            app.t4_disp.Value = 500;
            app.p5_disp.Value = 10;
            app.t7_disp.Value = 395;
            app.p8_disp.Value = 0.1;
            app.p13_disp.Value = 10;
            app.t16_disp.Value = 300;
            app.mdot.Value = 28.4368;

            app.t1_slider.Value = 500;
            app.p1_slider.Value = 200;
            app.p2_slider.Value = 100;
            app.t4_slider.Value = 500;
            app.p5_slider.Value = 10;
            app.t7_slider.Value = 395;
            app.p8_slider.Value = 0.1;
            app.p13_slider.Value = 10;
            app.t16_slider.Value = 300;
            app.mdot_slider.Value = 28.4368;
        end

        % Button pushed function: calc_button
        function calc_buttonButtonPushed(app, event)
            cla(app.Ts_graph);
            [P,h,s,T] = calculations(app);
            Ts_graph_func(app,P,h,s,T);
            app.finish_lamp.Color = 'g'
        end

        % Value changing function: t1_slider
        function t1_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.t1_disp.Value = changingValue;
        end

        % Value changing function: p2_slider
        function p2_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.p2_disp.Value = changingValue;
        end

        % Value changing function: t4_slider
        function t4_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.t4_disp.Value = changingValue;
        end

        % Value changing function: p5_slider
        function p5_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.p5_disp.Value = changingValue;
        end

        % Value changing function: t7_slider
        function t7_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.t7_disp.Value = changingValue;
        end

        % Value changing function: p8_slider
        function p8_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.p8_disp.Value = changingValue;
        end

        % Value changing function: p13_slider
        function p13_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.p13_disp.Value = changingValue;
        end

        % Value changing function: t16_slider
        function t16_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.t16_disp.Value = changingValue;
        end

        % Value changing function: p1_slider
        function p1_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.p1_disp.Value = changingValue;
        end

        % Value changing function: mdot_slider
        function mdot_sliderValueChanging(app, event)
            changingValue = event.Value;
            app.mdot.Value = changingValue;
        end

        % Button pushed function: reset_button
        function reset_buttonButtonPushed(app, event)
            startupFcn(app)
            cla(app.Ts_graph);
            app.finish_lamp.Color = 'cyan'
            pause(0.5)
            app.finish_lamp.Color = 'b'
        end

        % Button pushed function: reset_graph_button
        function reset_graph_buttonButtonPushed(app, event)
            startupFcn(app)
            cla(app.Ts_graph);
            [P,h,s,T] = calculations(app);
            Ts_graph_func(app,P,h,s,T);
            app.finish_lamp.Color = 'magenta'
            pause(0.5)
            app.finish_lamp.Color = 'b'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Ts_graph = uiaxes(app.UIFigure);
            title(app.Ts_graph, 'Temperature Entropy Diagram')
            xlabel(app.Ts_graph, 'Entropy kJ/(kg K)')
            ylabel(app.Ts_graph, 'Temperature (C)')
            zlabel(app.Ts_graph, 'Z')
            app.TemperatureT1CEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureT1CEditFieldLabel.Text = 'Temperature T1 (C)';
            app.t1_disp = uieditfield(app.UIFigure, 'numeric');
            app.Pressurep1barLabel = uilabel(app.UIFigure);
            app.Pressurep1barLabel.Text = 'Pressure p1 (bar)';
            app.p1_disp = uieditfield(app.UIFigure, 'numeric');
            app.Pressurep2barLabel = uilabel(app.UIFigure);
            app.Pressurep2barLabel.Text = 'Pressure p2 (bar)';
            app.p2_disp = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureT4CEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureT4CEditFieldLabel.Text = 'Temperature T4 (C)';
            app.t4_disp = uieditfield(app.UIFigure, 'numeric');
            app.Pressurep5barLabel = uilabel(app.UIFigure);
            app.Pressurep5barLabel.Text = 'Pressure p5 (bar)';
            app.p5_disp = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureT7CEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureT7CEditFieldLabel.Text = 'Temperature T7 (C)';
            app.t7_disp = uieditfield(app.UIFigure, 'numeric');
            app.Pressurep8barLabel = uilabel(app.UIFigure);
            app.Pressurep8barLabel.Text = 'Pressure p8 (bar)';
            app.p8_disp = uieditfield(app.UIFigure, 'numeric');
            app.Pressurep13MPaLabel = uilabel(app.UIFigure);
            app.Pressurep13MPaLabel.Text = 'Pressure p13 (bar)';
            app.p13_disp = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureT16CLabel = uilabel(app.UIFigure);
            app.TemperatureT16CLabel.Text = 'Temperature T16 (C)';
            app.t16_disp = uieditfield(app.UIFigure, 'numeric');
            app.MassFlowRatekgsEditFieldLabel = uilabel(app.UIFigure);
            app.MassFlowRatekgsEditFieldLabel.Text = 'Mass Flow Rate (kg/s)';
            app.mdot = uieditfield(app.UIFigure, 'numeric');
            app.calc_button = uibutton(app.UIFigure, 'push');
            app.calc_button.ButtonPushedFcn = createCallbackFcn(app, @calc_buttonButtonPushed, true);
            app.calc_button.Text = 'Calculate';
            app.FinishLabel = uilabel(app.UIFigure);
            app.FinishLabel.Text = 'Status';
            app.finish_lamp = uilamp(app.UIFigure);
            app.CyclePowerLabel = uilabel(app.UIFigure);
            app.CyclePowerLabel.Text = 'Cycle Power (MW)';
            app.W_cyc_P = uieditfield(app.UIFigure, 'numeric');
            app.CyclePowerLabel_2 = uilabel(app.UIFigure);
            app.CyclePowerLabel_2.Text = 'Electrical Efficiency %:';
            app.eta_electrical = uieditfield(app.UIFigure, 'numeric');
            app.CyclePowerLabel_3 = uilabel(app.UIFigure);
            app.CyclePowerLabel_3.Text = 'Thermal Efficiency %:';
            app.eta_thermal = uieditfield(app.UIFigure, 'numeric');
            app.CyclePowerLabel_4 = uilabel(app.UIFigure);
            app.CyclePowerLabel_4.Text = 'Cogeneration Efficiency %:';
            app.eta_cogeneration = uieditfield(app.UIFigure, 'numeric');
            app.t1_slider = uislider(app.UIFigure);
            app.t1_slider.Limits = [450 550];
            app.t1_slider.ValueChangingFcn = createCallbackFcn(app, @t1_sliderValueChanging, true);
            app.t1_slider.Value = 450;
            app.p2_slider = uislider(app.UIFigure);
            app.p2_slider.Limits = [50 150];
            app.p2_slider.ValueChangingFcn = createCallbackFcn(app, @p2_sliderValueChanging, true);
            app.p2_slider.Value = 50;
            app.t4_slider = uislider(app.UIFigure);
            app.t4_slider.Limits = [450 550];
            app.t4_slider.ValueChangingFcn = createCallbackFcn(app, @t4_sliderValueChanging, true);
            app.t4_slider.Value = 450;
            app.p5_slider = uislider(app.UIFigure);
            app.p5_slider.Limits = [8 12];
            app.p5_slider.ValueChangingFcn = createCallbackFcn(app, @p5_sliderValueChanging, true);
            app.p5_slider.Value = 8;
            app.t7_slider = uislider(app.UIFigure);
            app.t7_slider.Limits = [350 450];
            app.t7_slider.ValueChangingFcn = createCallbackFcn(app, @t7_sliderValueChanging, true);
            app.t7_slider.Value = 350;
            app.p8_slider = uislider(app.UIFigure);
            app.p8_slider.Limits = [0.05 0.15];
            app.p8_slider.ValueChangingFcn = createCallbackFcn(app, @p8_sliderValueChanging, true);
            app.p8_slider.Value = 0.05;
            app.p13_slider = uislider(app.UIFigure);
            app.p13_slider.Limits = [5 15];
            app.p13_slider.ValueChangingFcn = createCallbackFcn(app, @p13_sliderValueChanging, true);
            app.p13_slider.Value = 5;
            app.t16_slider = uislider(app.UIFigure);
            app.t16_slider.Limits = [250 350];
            app.t16_slider.ValueChangingFcn = createCallbackFcn(app, @t16_sliderValueChanging, true);
            app.t16_slider.Value = 250;
            app.p1_slider = uislider(app.UIFigure);
            app.p1_slider.Limits = [150 250];
            app.p1_slider.ValueChangingFcn = createCallbackFcn(app, @p1_sliderValueChanging, true);
            app.p1_slider.Value = 150;
            app.mdot_slider = uislider(app.UIFigure);
            app.mdot_slider.Limits = [25 35];
            app.mdot_slider.ValueChangingFcn = createCallbackFcn(app, @mdot_sliderValueChanging, true);
            app.mdot_slider.Value = 25;
            app.Disclaimer = uilabel(app.UIFigure);
            app.Disclaimer.Text = 'NOTE: Some of the points on the Ts Diagram are very close to each other making them look like they are overlapping';
            app.reset_button = uibutton(app.UIFigure, 'push');
            app.reset_button.ButtonPushedFcn = createCallbackFcn(app, @reset_buttonButtonPushed, true);
            app.reset_button.Text = 'Reset';
            app.reset_graph_button = uibutton(app.UIFigure, 'push');
            app.reset_graph_button.ButtonPushedFcn = createCallbackFcn(app, @reset_graph_buttonButtonPushed, true);
            app.reset_graph_button.Text = 'Reset Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cogeneration_Cycle_App_Formatted

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end